classdef dcMtr_BehaviorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SimulateButton                 matlab.ui.control.Button
        SetInputParametersPanel        matlab.ui.container.Panel
        BreakawayTorqueTbrkEditField   matlab.ui.control.NumericEditField
        BreakawayTorqueTbrkEditFieldLabel  matlab.ui.control.Label
        ArmatureResistanceREditField   matlab.ui.control.NumericEditField
        ArmatureResistanceREditFieldLabel  matlab.ui.control.Label
        AmrmatureInductanceLEditField  matlab.ui.control.NumericEditField
        AmrmatureInductanceLEditFieldLabel  matlab.ui.control.Label
        TorqueConstantKEditField       matlab.ui.control.NumericEditField
        TorqueConstantKEditFieldLabel  matlab.ui.control.Label
        RotiorInertiaJEditField        matlab.ui.control.NumericEditField
        RotiorInertiaJEditFieldLabel   matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        %# function ssc_dcmtr_sl.slx
        %# DCMotor.png
        
        modelName = 'ssc_dcmtr_sl';

    end
    
    methods (Access = private)


        function simInp = createSimulationInput(app)
            % Fcn to load and setup the simulation initial settings
            
            % Create an empty SimulationInput object
            simInp = Simulink.SimulationInput(app.modelName);
           
            % Load the parameters values from the ui edit fields
            simInp = simInp.setVariable('J',app.RotiorInertiaJEditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('K',app.TorqueConstantKEditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('L',app.AmrmatureInductanceLEditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('R',app.ArmatureResistanceREditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('Tbrk',app.BreakawayTorqueTbrkEditField.Value,'Workspace',app.modelName);

            % Configure the Simulation App to be deployable
            simInp = simulink.compiler.configureForDeployment(simInp);

        end
    end
    
    methods (Access = public)
        
        function processSimData(app,y)
            disp("simulation completed!")
            plot(app.UIAxes,y.logsout{1}.Values)
            hold (app.UIAxes,"on")
            plot(app.UIAxes,y.logsout{2}.Values)
            hold (app.UIAxes,"off")
            legend(app.UIAxes,{'w_{Simulink}','w_{Simscape}'})
       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start Simulink
            start_simulink;
            app.SimulateButton.Enable = "on";
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                if app.SimulateButton.Enable == "on"
                        % Turn off Simulate Button
                        app.SimulateButton.Enable = "off";
                        app.SimulateButton.Text = "Simulating...";
                        pause(0.01)
                        % Create the simulation input
                        simInp = app.createSimulationInput();
                        % Start Simulation
                        y = sim(simInp);

                        % display sim results
                        plot(app.UIAxes_2,y.logsout{3}.Values.Time,y.logsout{3}.Values.Data)

                        % display sim results
                        plot(app.UIAxes,y.logsout{1}.Values.Time,y.logsout{1}.Values.Data)
                        hold (app.UIAxes,"on")
                        plot(app.UIAxes,y.logsout{2}.Values.Time,y.logsout{2}.Values.Data)
                        hold (app.UIAxes,"off")
                        legend(app.UIAxes,{'w_{Simulink}','w_{Simscape}'})

                else
                    % Do nothing.
                    % while a simulation is running everything
                    % else should be non interactive.
                end
            catch ME
                % Handle error during sim
                uialert(app.UIFigure, ME.getReport(), 'Error');
            end
            app.SimulateButton.Text = "Simulate";
            app.SimulateButton.Enable = "on";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulink vs Simscape')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'angular speed (rad/sec)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Input Signal')
            xlabel(app.UIAxes_2, 'time (s)')
            ylabel(app.UIAxes_2, 'voltage (v)')
            zlabel(app.UIAxes_2, 'Z')
            app.SetInputParametersPanel = uipanel(app.UIFigure);
            app.SetInputParametersPanel.Title = 'Set Input Parameters';
            app.RotiorInertiaJEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.RotiorInertiaJEditFieldLabel.Text = 'Rotior Inertia (J)';
            app.RotiorInertiaJEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.RotiorInertiaJEditField.Limits = [0 Inf];
            app.RotiorInertiaJEditField.Value = 0.095;
            app.TorqueConstantKEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.TorqueConstantKEditFieldLabel.Text = 'Torque Constant (K)';
            app.TorqueConstantKEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.TorqueConstantKEditField.Limits = [0 Inf];
            app.TorqueConstantKEditField.Value = 0.5;
            app.AmrmatureInductanceLEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.AmrmatureInductanceLEditFieldLabel.Text = 'Amrmature Inductance (L)';
            app.AmrmatureInductanceLEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.AmrmatureInductanceLEditField.Limits = [0 Inf];
            app.AmrmatureInductanceLEditField.Value = 0.00032;
            app.ArmatureResistanceREditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.ArmatureResistanceREditFieldLabel.Text = 'Armature Resistance (R)';
            app.ArmatureResistanceREditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.ArmatureResistanceREditField.Limits = [0 Inf];
            app.ArmatureResistanceREditField.Value = 5;
            app.BreakawayTorqueTbrkEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.BreakawayTorqueTbrkEditFieldLabel.Text = 'Breakaway Torque (Tbrk)';
            app.BreakawayTorqueTbrkEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.BreakawayTorqueTbrkEditField.Limits = [0 Inf];
            app.BreakawayTorqueTbrkEditField.Value = 0.001;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DCMotor.png';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'DC Motor Behavior Model'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dcMtr_BehaviorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end