classdef dcMtr_BehaviorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TextArea                 matlab.ui.control.TextArea
        TextAreaLabel            matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        SimulateButton           matlab.ui.control.Button
        SetInputParametersPanel  matlab.ui.container.Panel
        T_breakEditField         matlab.ui.control.NumericEditField
        T_breakEditFieldLabel    matlab.ui.control.Label
        REditField               matlab.ui.control.NumericEditField
        REditFieldLabel          matlab.ui.control.Label
        LEditField               matlab.ui.control.NumericEditField
        LEditFieldLabel          matlab.ui.control.Label
        KEditField               matlab.ui.control.NumericEditField
        KEditFieldLabel          matlab.ui.control.Label
        JEditField               matlab.ui.control.NumericEditField
        JEditFieldLabel          matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        %# function ssc_dcmtr_sl.slx
        modelName = 'ssc_dcmtr_sl';

    end
    
    methods (Access = private)


        function simInp = createSimulationInput(app)
            % Fcn to load and setup the simulation initial settings
            
            % Create an empty SimulationInput object
            simInp = Simulink.SimulationInput(app.modelName);
           
            % Load the parameters values from the ui edit fields
            simInp = simInp.setVariable('J',app.JEditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('K',app.KEditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('L',app.LEditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('R',app.REditField.Value,'Workspace',app.modelName);
            simInp = simInp.setVariable('Tbrk',app.T_breakEditField.Value,'Workspace',app.modelName);

            % Configure the Simulation App to be deployable
            simInp = simulink.compiler.configureForDeployment(simInp);

        end
    end
    
    methods (Access = public)
        
        function processSimData(app,y)
            disp("simulation completed!")
            plot(app.UIAxes,y.logsout{1}.Values)
            hold (app.UIAxes,"on")
            plot(app.UIAxes,y.logsout{2}.Values)
            hold (app.UIAxes,"off")
            legend(app.UIAxes,{'w_{Simulink}','w_{Simscape}'})
       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start Simulink
            start_simulink;
            app.SimulateButton.Enable = "on";
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                if app.SimulateButton.Enable == "on"
                        % Turn off Simulate Button
                        app.SimulateButton.Enable = "off";
                        pause(0.01)
                        % Create the simulation input
                        simInp = app.createSimulationInput();
                        % Start Simulation
                        y = sim(simInp);
                        
                        disp("simulation completed!")

                        % display sim results
                        plot(app.UIAxes,y.logsout{1}.Values.Time,y.logsout{1}.Values.Data)
                        hold (app.UIAxes,"on")
                        plot(app.UIAxes,y.logsout{2}.Values.Time,y.logsout{2}.Values.Data)
                        hold (app.UIAxes,"off")
                        legend(app.UIAxes,{'w_{Simulink}','w_{Simscape}'})

                else
                    % Do nothing.
                    % while a simulation is running everything
                    % else should be non interactive.
                end
            catch ME
                % Handle error during sim
                uialert(app.UIFigure, ME.getReport(), 'Error');
            end
            app.SimulateButton.Enable = "on";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulink vs Simscape')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'angular speed (rad/sec)')
            zlabel(app.UIAxes, 'Z')
            app.SetInputParametersPanel = uipanel(app.UIFigure);
            app.SetInputParametersPanel.Title = 'Set Input Parameters';
            app.JEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.JEditField.Limits = [0 Inf];
            app.JEditField.Value = 0.095;
            app.KEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.KEditField.Limits = [0 Inf];
            app.KEditField.Value = 0.5;
            app.LEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.LEditField.Limits = [0 Inf];
            app.LEditField.Value = 0.00032;
            app.REditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.REditField.Limits = [0 Inf];
            app.REditField.Value = 5;
            app.T_breakEditFieldLabel = uilabel(app.SetInputParametersPanel);
            app.T_breakEditFieldLabel.Text = 'T_break';
            app.T_breakEditField = uieditfield(app.SetInputParametersPanel, 'numeric');
            app.T_breakEditField.Limits = [0 Inf];
            app.T_breakEditField.Value = 0.001;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DCMotor.png';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'DC Motor Behavior Model'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dcMtr_BehaviorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end